classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Raytracing_PathlossUIFigure  matlab.ui.Figure
        running_label                matlab.ui.control.Label
        running_status               matlab.ui.control.Lamp
        GeneralPanel                 matlab.ui.container.Panel
        freq                         matlab.ui.control.NumericEditField
        quads                        matlab.ui.control.NumericEditField
        general_set                  matlab.ui.control.Button
        freq_drop                    matlab.ui.control.DropDown
        NumberofMirrorsLabel         matlab.ui.control.Label
        FrequencyLabel               matlab.ui.control.Label
        MenuPanel                    matlab.ui.container.Panel
        num_of_rays                  matlab.ui.control.DropDown
        RayspercellsurfaceLabel      matlab.ui.control.Label
        SimulationPanel              matlab.ui.container.Panel
        fit_data                     matlab.ui.control.Button
        start_raytracing             matlab.ui.control.Button
        PlotScenePanel               matlab.ui.container.Panel
        plot_scene                   matlab.ui.control.Button
        show_vectors                 matlab.ui.control.CheckBox
        show_mirrors                 matlab.ui.control.CheckBox
        show_trx                     matlab.ui.control.CheckBox
        show_lines                   matlab.ui.control.CheckBox
        Plane2Panel                  matlab.ui.container.Panel
        p2_size                      matlab.ui.control.NumericEditField
        p2_phi                       matlab.ui.control.NumericEditField
        p2_z                         matlab.ui.control.NumericEditField
        p2_y                         matlab.ui.control.NumericEditField
        p2_x                         matlab.ui.control.NumericEditField
        p2_set                       matlab.ui.control.Button
        mLabel_36                    matlab.ui.control.Label
        degLabel_8                   matlab.ui.control.Label
        mLabel_35                    matlab.ui.control.Label
        mLabel_34                    matlab.ui.control.Label
        degLabel_7                   matlab.ui.control.Label
        p2_theta                     matlab.ui.control.NumericEditField
        mLabel_33                    matlab.ui.control.Label
        PlanesizeLabel_3             matlab.ui.control.Label
        AnglePhiLabel_3              matlab.ui.control.Label
        AngleThetaLabel_3            matlab.ui.control.Label
        ZPositionLabel_6             matlab.ui.control.Label
        YPositionLabel_6             matlab.ui.control.Label
        XPositionLabel_6             matlab.ui.control.Label
        RxPanel                      matlab.ui.container.Panel
        rx_p                         matlab.ui.control.NumericEditField
        rx_set                       matlab.ui.control.Button
        rx_z                         matlab.ui.control.NumericEditField
        mLabel_32                    matlab.ui.control.Label
        rx_y                         matlab.ui.control.NumericEditField
        mLabel_31                    matlab.ui.control.Label
        rx_x                         matlab.ui.control.NumericEditField
        mLabel_30                    matlab.ui.control.Label
        rx_p_drop                    matlab.ui.control.DropDown
        PowerLabel_3                 matlab.ui.control.Label
        ZPositionLabel_5             matlab.ui.control.Label
        YPositionLabel_5             matlab.ui.control.Label
        XPositionLabel_5             matlab.ui.control.Label
        TxPanel                      matlab.ui.container.Panel
        tx_p                         matlab.ui.control.NumericEditField
        tx_p_drop                    matlab.ui.control.DropDown
        tx_set                       matlab.ui.control.Button
        tx_z                         matlab.ui.control.NumericEditField
        mLabel_29                    matlab.ui.control.Label
        tx_y                         matlab.ui.control.NumericEditField
        mLabel_28                    matlab.ui.control.Label
        tx_x                         matlab.ui.control.NumericEditField
        mLabel_27                    matlab.ui.control.Label
        PowerLabel_2                 matlab.ui.control.Label
        ZPositionLabel_4             matlab.ui.control.Label
        YPositionLabel_4             matlab.ui.control.Label
        XPositionLabel_4             matlab.ui.control.Label
        Plane1Panel                  matlab.ui.container.Panel
        p1_size                      matlab.ui.control.NumericEditField
        p1_phi                       matlab.ui.control.NumericEditField
        p1_z                         matlab.ui.control.NumericEditField
        p1_y                         matlab.ui.control.NumericEditField
        p1_x                         matlab.ui.control.NumericEditField
        p1_set                       matlab.ui.control.Button
        mLabel_12                    matlab.ui.control.Label
        degLabel_6                   matlab.ui.control.Label
        mLabel_11                    matlab.ui.control.Label
        mLabel_10                    matlab.ui.control.Label
        degLabel_5                   matlab.ui.control.Label
        p1_theta                     matlab.ui.control.NumericEditField
        mLabel_9                     matlab.ui.control.Label
        PlanesizeLabel_2             matlab.ui.control.Label
        AnglePhiLabel_2              matlab.ui.control.Label
        AngleThetaLabel_2            matlab.ui.control.Label
        ZPositionLabel               matlab.ui.control.Label
        YPositionLabel               matlab.ui.control.Label
        XPositionLabel               matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        tx_3d                        matlab.ui.control.UIAxes
        rx_3d                        matlab.ui.control.UIAxes
        rx_2d                        matlab.ui.control.UIAxes
        tx_2d                        matlab.ui.control.UIAxes
    end

    properties (Access = private)
        environment=TEnvironment();
        L=0;
        x=[];
        y=[];
        dataChanged=1;
        prev_rays=0;
    end

    methods (Access = private)
        
        function plotRadiationPatterns(app)
	        paintAntennaPowerPat(app.tx_2d, app.tx_3d, ...
                            app.environment.Sender.Antenna, app.environment);
	        
            paintAntennaPowerPat(app.rx_2d, app.rx_3d, ...
                            app.environment.Receiver.Antenna, app.environment);            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.environment=TEnvironment();
            app.environment.Medium=TFreeSpaceMedium;
            
            F=applyUnitScale(app.freq.Value,app.freq_drop.Value);

            app.L=app.environment.Medium.c/F;

            app.environment.Sender=TNode();
            app.environment.Sender.Position=[app.tx_x.Value,app.tx_y.Value,app.tx_z.Value];
            app.environment.Sender.Signal.P=applyUnitScale(app.tx_p.Value,app.tx_p_drop.Value);
            app.environment.Sender.Signal.F=F;

            app.environment.Receiver=TNode();
            app.environment.Receiver.Position=[app.rx_x.Value,app.rx_y.Value,app.rx_z.Value];
            app.environment.Receiver.Signal.P=applyUnitScale(app.rx_p.Value,app.rx_p_drop.Value);
            app.environment.Receiver.Signal.F=F;

            app.environment.Plane1=Plane();
            app.environment.Plane1.Position=[app.p1_x.Value,app.p1_y.Value,app.p1_z.Value];
            app.environment.Plane1.Theta=app.p1_theta.Value;
            app.environment.Plane1.Phi=app.p1_phi.Value;
            app.environment.Plane1.Size=app.p1_size.Value;
            app.environment.Plane1.Quads=app.quads.Value;

            app.environment.Plane2=Plane();
            app.environment.Plane2.Position=[app.p2_x.Value,app.p2_y.Value,app.p2_z.Value];
            app.environment.Plane2.Theta=app.p2_theta.Value;
            app.environment.Plane2.Phi=app.p2_phi.Value;
            app.environment.Plane2.Size=app.p2_size.Value;
            app.environment.Plane2.Quads=app.quads.Value;

            plotRadiationPatterns(app);
        end

        % Value changed function: p1_phi, p1_size, p1_theta, p1_x, p1_y, 
        % ...and 1 other component
        function p1_en(app, event)
            app.p1_set.Enable=1;
        end

        % Button pushed function: p1_set
        function p1_dis(app, event)
            app.environment.Plane1.Position=[app.p1_x.Value,app.p1_y.Value,app.p1_z.Value];
            app.environment.Plane1.Theta=app.p1_theta.Value;
            app.environment.Plane1.Phi=app.p1_phi.Value;
            app.environment.Plane1.Size=app.p1_size.Value;
            app.environment.Plane1.Quads=app.quads.Value;
            app.p1_set.Enable=0;
            app.dataChanged=1;
        end

        % Value changed function: tx_p, tx_p_drop, tx_x, tx_y, tx_z
        function tx_en(app, event)
            app.tx_set.Enable=1;
        end

        % Button pushed function: tx_set
        function tx_dis(app, event)
            F=applyUnitScale(app.freq.Value,app.freq_drop.Value);

            app.L=app.environment.Medium.c/F;

            app.environment.Sender=TNode();
            app.environment.Sender.Position=[app.tx_x.Value,app.tx_y.Value,app.tx_z.Value];
            app.environment.Sender.Signal.P=applyUnitScale(app.tx_p.Value,app.tx_p_drop.Value);
            app.environment.Sender.Signal.F=F;
            plotRadiationPatterns(app);
            app.tx_set.Enable=0;
            app.dataChanged=1;
        end

        % Value changed function: rx_p, rx_p_drop, rx_x, rx_y, rx_z
        function rx_en(app, event)
            app.rx_set.Enable=1;
        end

        % Button pushed function: rx_set
        function rx_dis(app, event)
            F=applyUnitScale(app.freq.Value,app.freq_drop.Value);

            app.L=app.environment.Medium.c/F;

            app.environment.Receiver=TNode();
            app.environment.Receiver.Position=[app.rx_x.Value,app.rx_y.Value,app.rx_z.Value];
            app.environment.Receiver.Signal.P=applyUnitScale(app.rx_p.Value,app.rx_p_drop.Value);
            app.environment.Receiver.Signal.F=F;
            plotRadiationPatterns(app);
            app.rx_set.Enable=0;
            app.dataChanged=1;
        end

        % Value changed function: p2_phi, p2_size, p2_theta, p2_x, p2_y, 
        % ...and 1 other component
        function p2_en(app, event)
            app.p2_set.Enable=1;
        end

        % Button pushed function: p2_set
        function p2_dis(app, event)
            app.environment.Plane2.Position=[app.p2_x.Value,app.p2_y.Value,app.p2_z.Value];
            app.environment.Plane2.Theta=app.p2_theta.Value;
            app.environment.Plane2.Phi=app.p2_phi.Value;
            app.environment.Plane2.Size=app.p2_size.Value;
            app.environment.Plane2.Quads=app.quads.Value;
            app.p2_set.Enable=0;
            app.dataChanged=1;
        end

        % Value changed function: freq, freq_drop, quads
        function general_en(app, event)
            app.general_set.Enable=1;
        end

        % Button pushed function: general_set
        function general_dis(app, event)
            app.general_set.Enable=0;
            app.dataChanged=1;
        end

        % Button pushed function: plot_scene
        function plot_sceneButtonPushed(app, event)
            p1_dis(app, event);
            p2_dis(app, event);
            tx_dis(app, event);
            rx_dis(app, event);
            general_dis(app, event);
            showLines=app.show_lines.Value;
            showTRx=app.show_trx.Value;
            showMirrors=app.show_mirrors.Value;
            showVectors=app.show_vectors.Value;
            rays=str2double(app.num_of_rays.Value);
            app.running_status.Color=[0.00,1.00,0.00];
            app.running_label.Text='Busy, "Plot Scene"';
            pause(0);
            tic;
            paintScene(app.environment,showLines,showTRx,showMirrors,showVectors,rays);
            end_time=toc;
            app.running_status.Color=[0.8,0.8,0.8];
            app.running_label.Text=['Idle, "Plot Scene" took: ',num2str(round(end_time,2)),' seconds'];
        end

        % Button pushed function: start_raytracing
        function start_raytracingButtonPushed(app, event)
            p1_dis(app, event);
            p2_dis(app, event);
            tx_dis(app, event);
            rx_dis(app, event);
            general_dis(app, event);
            app.running_status.Color=[0.00,1.00,0.00];
            rays=str2double(app.num_of_rays.Value);
            pause(0);
            tic;
            [app.x,app.y]=generatePathLossGraph(app.environment,app.L,rays,app.running_label);
            app3(app.x,app.y);
            end_time=toc;
            app.running_status.Color=[0.8,0.8,0.8];
            app.running_label.Text=['Idle, "Ray Tracing" took: ',num2str(round(end_time,2)),' seconds'];
            app.fit_data.Enable=1;
        end

        % Button pushed function: fit_data
        function fit_dataButtonPushed(app, event)
            app.running_status.Color=[0.00,1.00,0.00];
            app.running_label.Text='Busy, "Fit Data"';
            pause(0);
            tic;
            app2(app.x,app.y,app.L);
            end_time=toc;
            app.running_status.Color=[0.8,0.8,0.8];
            app.running_label.Text=['Idle, "Fit Data" took: ',num2str(round(end_time,2)),' seconds'];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Raytracing_PathlossUIFigure = uifigure('Visible', 'off');
            app.Raytracing_PathlossUIFigure.Name = 'Raytracing_Pathloss';
            app.Raytracing_PathlossUIFigure.Icon = fullfile(pathToMLAPP, 'antenna_ico.png');
            app.tx_2d = uiaxes(app.Raytracing_PathlossUIFigure);
            title(app.tx_2d, 'Transmitter Radiation Pattern 2D')
            xlabel(app.tx_2d, 'X')
            ylabel(app.tx_2d, 'Y')
            zlabel(app.tx_2d, 'Z')
            app.rx_2d = uiaxes(app.Raytracing_PathlossUIFigure);
            title(app.rx_2d, 'Receiver Radiation Pattern 2D')
            xlabel(app.rx_2d, 'X')
            ylabel(app.rx_2d, 'Y')
            zlabel(app.rx_2d, 'Z')
            app.rx_3d = uiaxes(app.Raytracing_PathlossUIFigure);
            title(app.rx_3d, 'Receiver Radiation Pattern 3D')
            xlabel(app.rx_3d, 'X')
            ylabel(app.rx_3d, 'Y')
            zlabel(app.rx_3d, 'Z')
            app.tx_3d = uiaxes(app.Raytracing_PathlossUIFigure);
            title(app.tx_3d, 'Transmitter Radiation Pattern 3D')
            xlabel(app.tx_3d, 'X')
            ylabel(app.tx_3d, 'Y')
            zlabel(app.tx_3d, 'Z')
            app.Image = uiimage(app.Raytracing_PathlossUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'back.png');
            app.Plane1Panel = uipanel(app.Raytracing_PathlossUIFigure);
            app.Plane1Panel.Title = 'Plane1';
            app.XPositionLabel = uilabel(app.Plane1Panel);
            app.XPositionLabel.Text = 'X Position = ';
            app.YPositionLabel = uilabel(app.Plane1Panel);
            app.YPositionLabel.Text = 'Y Position = ';
            app.ZPositionLabel = uilabel(app.Plane1Panel);
            app.ZPositionLabel.Text = 'Z Position = ';
            app.AngleThetaLabel_2 = uilabel(app.Plane1Panel);
            app.AngleThetaLabel_2.Text = 'Angle Theta = ';
            app.AnglePhiLabel_2 = uilabel(app.Plane1Panel);
            app.AnglePhiLabel_2.Text = 'Angle Phi = ';
            app.PlanesizeLabel_2 = uilabel(app.Plane1Panel);
            app.PlanesizeLabel_2.Text = 'Plane size = ';
            app.mLabel_9 = uilabel(app.Plane1Panel);
            app.mLabel_9.Text = 'm';
            app.p1_theta = uieditfield(app.Plane1Panel, 'numeric');
            app.p1_theta.ValueChangedFcn = createCallbackFcn(app, @p1_en, true);
            app.p1_theta.Value = 15;
            app.degLabel_5 = uilabel(app.Plane1Panel);
            app.degLabel_5.Text = 'deg';
            app.mLabel_10 = uilabel(app.Plane1Panel);
            app.mLabel_10.Text = 'm';
            app.mLabel_11 = uilabel(app.Plane1Panel);
            app.mLabel_11.Text = 'm';
            app.degLabel_6 = uilabel(app.Plane1Panel);
            app.degLabel_6.Text = 'deg';
            app.mLabel_12 = uilabel(app.Plane1Panel);
            app.mLabel_12.Text = 'm';
            app.p1_set = uibutton(app.Plane1Panel, 'push');
            app.p1_set.ButtonPushedFcn = createCallbackFcn(app, @p1_dis, true);
            app.p1_set.Enable = 'off';
            app.p1_set.Text = 'Set Values';
            app.p1_x = uieditfield(app.Plane1Panel, 'numeric');
            app.p1_x.ValueChangedFcn = createCallbackFcn(app, @p1_en, true);
            app.p1_y = uieditfield(app.Plane1Panel, 'numeric');
            app.p1_y.ValueChangedFcn = createCallbackFcn(app, @p1_en, true);
            app.p1_y.Value = 1.5;
            app.p1_z = uieditfield(app.Plane1Panel, 'numeric');
            app.p1_z.ValueChangedFcn = createCallbackFcn(app, @p1_en, true);
            app.p1_phi = uieditfield(app.Plane1Panel, 'numeric');
            app.p1_phi.ValueChangedFcn = createCallbackFcn(app, @p1_en, true);
            app.p1_size = uieditfield(app.Plane1Panel, 'numeric');
            app.p1_size.ValueChangedFcn = createCallbackFcn(app, @p1_en, true);
            app.p1_size.Tooltip = {'The size of a side of the Plane (ex 10 for 10m x 10m Plane)'};
            app.p1_size.Value = 10;
            app.TxPanel = uipanel(app.Raytracing_PathlossUIFigure);
            app.TxPanel.Title = 'Tx';
            app.XPositionLabel_4 = uilabel(app.TxPanel);
            app.XPositionLabel_4.Text = 'X Position = ';
            app.YPositionLabel_4 = uilabel(app.TxPanel);
            app.YPositionLabel_4.Text = 'Y Position = ';
            app.ZPositionLabel_4 = uilabel(app.TxPanel);
            app.ZPositionLabel_4.Text = 'Z Position = ';
            app.PowerLabel_2 = uilabel(app.TxPanel);
            app.PowerLabel_2.Text = 'Power = ';
            app.mLabel_27 = uilabel(app.TxPanel);
            app.mLabel_27.Text = 'm';
            app.tx_x = uieditfield(app.TxPanel, 'numeric');
            app.tx_x.ValueChangedFcn = createCallbackFcn(app, @tx_en, true);
            app.tx_x.Value = -5.5;
            app.mLabel_28 = uilabel(app.TxPanel);
            app.mLabel_28.Text = 'm';
            app.tx_y = uieditfield(app.TxPanel, 'numeric');
            app.tx_y.ValueChangedFcn = createCallbackFcn(app, @tx_en, true);
            app.tx_y.Value = 0.4;
            app.mLabel_29 = uilabel(app.TxPanel);
            app.mLabel_29.Text = 'm';
            app.tx_z = uieditfield(app.TxPanel, 'numeric');
            app.tx_z.ValueChangedFcn = createCallbackFcn(app, @tx_en, true);
            app.tx_z.Value = -2;
            app.tx_set = uibutton(app.TxPanel, 'push');
            app.tx_set.ButtonPushedFcn = createCallbackFcn(app, @tx_dis, true);
            app.tx_set.Enable = 'off';
            app.tx_set.Text = 'Set Values';
            app.tx_p_drop = uidropdown(app.TxPanel);
            app.tx_p_drop.Items = {'W', 'mW', 'dBW', 'dBmW'};
            app.tx_p_drop.ValueChangedFcn = createCallbackFcn(app, @tx_en, true);
            app.tx_p_drop.Tag = 'tx_p_drop';
            app.tx_p_drop.Value = 'dBW';
            app.tx_p = uieditfield(app.TxPanel, 'numeric');
            app.tx_p.ValueChangedFcn = createCallbackFcn(app, @tx_en, true);
            app.tx_p.Value = 1;
            app.RxPanel = uipanel(app.Raytracing_PathlossUIFigure);
            app.RxPanel.Title = 'Rx';
            app.XPositionLabel_5 = uilabel(app.RxPanel);
            app.XPositionLabel_5.Text = 'X Position = ';
            app.YPositionLabel_5 = uilabel(app.RxPanel);
            app.YPositionLabel_5.Text = 'Y Position = ';
            app.ZPositionLabel_5 = uilabel(app.RxPanel);
            app.ZPositionLabel_5.Text = 'Z Position = ';
            app.PowerLabel_3 = uilabel(app.RxPanel);
            app.PowerLabel_3.Text = 'Power = ';
            app.rx_p_drop = uidropdown(app.RxPanel);
            app.rx_p_drop.Items = {'W', 'mW', 'dBW', 'dBmW'};
            app.rx_p_drop.ValueChangedFcn = createCallbackFcn(app, @rx_en, true);
            app.rx_p_drop.Value = 'dBW';
            app.mLabel_30 = uilabel(app.RxPanel);
            app.mLabel_30.Text = 'm';
            app.rx_x = uieditfield(app.RxPanel, 'numeric');
            app.rx_x.ValueChangedFcn = createCallbackFcn(app, @rx_en, true);
            app.rx_x.Value = 5.5;
            app.mLabel_31 = uilabel(app.RxPanel);
            app.mLabel_31.Text = 'm';
            app.rx_y = uieditfield(app.RxPanel, 'numeric');
            app.rx_y.ValueChangedFcn = createCallbackFcn(app, @rx_en, true);
            app.rx_y.Value = -0.5;
            app.mLabel_32 = uilabel(app.RxPanel);
            app.mLabel_32.Text = 'm';
            app.rx_z = uieditfield(app.RxPanel, 'numeric');
            app.rx_z.ValueChangedFcn = createCallbackFcn(app, @rx_en, true);
            app.rx_z.Value = 2;
            app.rx_set = uibutton(app.RxPanel, 'push');
            app.rx_set.ButtonPushedFcn = createCallbackFcn(app, @rx_dis, true);
            app.rx_set.Enable = 'off';
            app.rx_set.Text = 'Set Values';
            app.rx_p = uieditfield(app.RxPanel, 'numeric');
            app.rx_p.ValueChangedFcn = createCallbackFcn(app, @rx_en, true);
            app.rx_p.Value = 1;
            app.Plane2Panel = uipanel(app.Raytracing_PathlossUIFigure);
            app.Plane2Panel.Title = 'Plane2';
            app.XPositionLabel_6 = uilabel(app.Plane2Panel);
            app.XPositionLabel_6.Text = 'X Position = ';
            app.YPositionLabel_6 = uilabel(app.Plane2Panel);
            app.YPositionLabel_6.Text = 'Y Position = ';
            app.ZPositionLabel_6 = uilabel(app.Plane2Panel);
            app.ZPositionLabel_6.Text = 'Z Position = ';
            app.AngleThetaLabel_3 = uilabel(app.Plane2Panel);
            app.AngleThetaLabel_3.Text = 'Angle Theta = ';
            app.AnglePhiLabel_3 = uilabel(app.Plane2Panel);
            app.AnglePhiLabel_3.Text = 'Angle Phi = ';
            app.PlanesizeLabel_3 = uilabel(app.Plane2Panel);
            app.PlanesizeLabel_3.Text = 'Plane size = ';
            app.mLabel_33 = uilabel(app.Plane2Panel);
            app.mLabel_33.Text = 'm';
            app.p2_theta = uieditfield(app.Plane2Panel, 'numeric');
            app.p2_theta.ValueChangedFcn = createCallbackFcn(app, @p2_en, true);
            app.degLabel_7 = uilabel(app.Plane2Panel);
            app.degLabel_7.Text = 'deg';
            app.mLabel_34 = uilabel(app.Plane2Panel);
            app.mLabel_34.Text = 'm';
            app.mLabel_35 = uilabel(app.Plane2Panel);
            app.mLabel_35.Text = 'm';
            app.degLabel_8 = uilabel(app.Plane2Panel);
            app.degLabel_8.Text = 'deg';
            app.mLabel_36 = uilabel(app.Plane2Panel);
            app.mLabel_36.Text = 'm';
            app.p2_set = uibutton(app.Plane2Panel, 'push');
            app.p2_set.ButtonPushedFcn = createCallbackFcn(app, @p2_dis, true);
            app.p2_set.Enable = 'off';
            app.p2_set.Text = 'Set Values';
            app.p2_x = uieditfield(app.Plane2Panel, 'numeric');
            app.p2_x.ValueChangedFcn = createCallbackFcn(app, @p2_en, true);
            app.p2_y = uieditfield(app.Plane2Panel, 'numeric');
            app.p2_y.ValueChangedFcn = createCallbackFcn(app, @p2_en, true);
            app.p2_y.Value = -1.5;
            app.p2_z = uieditfield(app.Plane2Panel, 'numeric');
            app.p2_z.ValueChangedFcn = createCallbackFcn(app, @p2_en, true);
            app.p2_phi = uieditfield(app.Plane2Panel, 'numeric');
            app.p2_phi.ValueChangedFcn = createCallbackFcn(app, @p2_en, true);
            app.p2_size = uieditfield(app.Plane2Panel, 'numeric');
            app.p2_size.ValueChangedFcn = createCallbackFcn(app, @p2_en, true);
            app.p2_size.Tooltip = {'The size of a side of the Plane (ex 10 for 10m x 10m Plane)'};
            app.p2_size.Value = 10;
            app.MenuPanel = uipanel(app.Raytracing_PathlossUIFigure);
            app.MenuPanel.Title = 'Menu';
            app.PlotScenePanel = uipanel(app.MenuPanel);
            app.PlotScenePanel.Title = 'Plot Scene';
            app.show_lines = uicheckbox(app.PlotScenePanel);
            app.show_lines.Text = 'Show Lines';
            app.show_lines.Value = true;
            app.show_trx = uicheckbox(app.PlotScenePanel);
            app.show_trx.Text = 'Show Tx and Rx';
            app.show_trx.Value = true;
            app.show_mirrors = uicheckbox(app.PlotScenePanel);
            app.show_mirrors.Text = 'Show Mirrors';
            app.show_mirrors.Value = true;
            app.show_vectors = uicheckbox(app.PlotScenePanel);
            app.show_vectors.Text = 'Show Vectors';
            app.plot_scene = uibutton(app.PlotScenePanel, 'push');
            app.plot_scene.ButtonPushedFcn = createCallbackFcn(app, @plot_sceneButtonPushed, true);
            app.plot_scene.Text = 'Plot Scene';
            app.SimulationPanel = uipanel(app.MenuPanel);
            app.SimulationPanel.Title = 'Simulation';
            app.start_raytracing = uibutton(app.SimulationPanel, 'push');
            app.start_raytracing.ButtonPushedFcn = createCallbackFcn(app, @start_raytracingButtonPushed, true);
            app.start_raytracing.Text = 'Start Ray Tracing';
            app.fit_data = uibutton(app.SimulationPanel, 'push');
            app.fit_data.ButtonPushedFcn = createCallbackFcn(app, @fit_dataButtonPushed, true);
            app.fit_data.Enable = 'off';
            app.fit_data.Text = 'Fit Data';
            app.RayspercellsurfaceLabel = uilabel(app.MenuPanel);
            app.RayspercellsurfaceLabel.Text = 'Rays per cell-surface = ';
            app.num_of_rays = uidropdown(app.MenuPanel);
            app.num_of_rays.Items = {'3', '5', '7', '9', '11', '13', '15', '17', '19'};
            app.num_of_rays.Tooltip = {'More rays makes it slower.'};
            app.num_of_rays.Value = '11';
            app.GeneralPanel = uipanel(app.Raytracing_PathlossUIFigure);
            app.GeneralPanel.Tooltip = {''};
            app.GeneralPanel.Title = 'General';
            app.FrequencyLabel = uilabel(app.GeneralPanel);
            app.FrequencyLabel.Text = 'Frequency = ';
            app.NumberofMirrorsLabel = uilabel(app.GeneralPanel);
            app.NumberofMirrorsLabel.Text = 'Number of Mirrors = ';
            app.freq_drop = uidropdown(app.GeneralPanel);
            app.freq_drop.Items = {'Hz', 'KHz', 'MHz', 'GHz', 'THz'};
            app.freq_drop.ValueChangedFcn = createCallbackFcn(app, @general_en, true);
            app.freq_drop.Value = 'GHz';
            app.general_set = uibutton(app.GeneralPanel, 'push');
            app.general_set.ButtonPushedFcn = createCallbackFcn(app, @general_dis, true);
            app.general_set.Enable = 'off';
            app.general_set.Text = 'Set Values';
            app.quads = uieditfield(app.GeneralPanel, 'numeric');
            app.quads.ValueChangedFcn = createCallbackFcn(app, @general_en, true);
            app.quads.Tooltip = {'Number of mirrors of one side per plane (ex 10 for 10x10 plane)'};
            app.quads.Value = 10;
            app.freq = uieditfield(app.GeneralPanel, 'numeric');
            app.freq.ValueChangedFcn = createCallbackFcn(app, @general_en, true);
            app.freq.Value = 60;
            app.running_status = uilamp(app.Raytracing_PathlossUIFigure);
            app.running_label = uilabel(app.Raytracing_PathlossUIFigure);
            app.running_label.Text = 'Idle';
            % Show the figure after all components are created
            app.Raytracing_PathlossUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Raytracing_PathlossUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Raytracing_PathlossUIFigure)
        end
    end
end